classdef app1_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UserDropDownLabel        matlab.ui.control.Label
        UserDropDown             matlab.ui.control.DropDown
        ExperimentDropDownLabel  matlab.ui.control.Label
        ExperimentDropDown       matlab.ui.control.DropDown
        DateandtimeLabel         matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        RunExperimentButton      matlab.ui.control.StateButton
        saveLabel                matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        ProgressTextLabel        matlab.ui.control.Label
        MousePanel               matlab.ui.container.Panel
        MouseIDDropDownLabel     matlab.ui.control.Label
        MouseIDDropDown          matlab.ui.control.DropDown
        DobLabel                 matlab.ui.control.Label
        SexLabel                 matlab.ui.control.Label
        GroupLabel               matlab.ui.control.Label
        GenotypeLabel            matlab.ui.control.Label
        AgeLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        group
        dob
        sex
        genotype
        datetimenow 
        age
        basefolder
        mouseID
        name
        exp
        savedir
        savefile
        data
       
    end
    
    properties (Access = public)
        ph
        th
        metadata
    end
    
  
    methods (Access = private)
        
        function results = read_spreadsheet(app)
            %load spreadsheet
            base_folder = fileparts(mfilename('fullpath'));
            spreadsheet_filename = fullfile(base_folder, 'data_MMC.xlsx');
           
            %extract values from spreadsheet 
            [~,app.name] = xlsread(spreadsheet_filename, "User", 'A2:A100');
            [~,experiment] = xlsread(spreadsheet_filename, "Experiment", 'A2:A100');
            app.exp = experiment;
            mouseID = xlsread(spreadsheet_filename, "Mouse", 'A2:A100');
            app.mouseID = string(mouseID);
            
            group = xlsread(spreadsheet_filename, "Mouse", 'B2:B100');
            sgroup = string(group);
            
            ndob = xlsread(spreadsheet_filename, "Mouse", 'c2:c100');
          
            if isempty(ndob)
                ndob = xlsread(spreadsheet_filename, "Mouse", 'C2:C100','basic');
            end
            
            sumdob = sum(ndob,2);
            chardob = datestr(sumdob+datenum('30-Dec-1899'));
            strdob = datetime(chardob,'InputFormat','dd-MMM-yyyy');
            sdob = string(strdob);
            nowt = datetime('now');
            timedate = string(between(strdob,nowt,'months'));
            
            [~,ssex] = xlsread(spreadsheet_filename, "Mouse", 'D2:D100');
            [~,sgenotype] = xlsread(spreadsheet_filename, "Mouse", 'E2:E100');
               
            app.group = sgroup;
            app.dob = sdob;
            app.sex = ssex;
            app.genotype = sgenotype;
            app.age = timedate;
           
            %values to app variables 
            app.UserDropDown.Items = app.name;
            app.ExperimentDropDown.Items = app.exp;
            app.MouseIDDropDown.Items = app.mouseID;
           
            
            %set up dropdown index 
            app.MouseIDDropDown.ItemsData = 1:200;

            app.data.time = [89 ,74, 62, 49, 40, 31];


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.basefolder = fileparts(mfilename('fullpath'));
            read_spreadsheet(app);
            
            D = fullfile(app.basefolder, 'data');
            if exist (D,'dir')
        
            else
                mkdir(D);
            end
            app.savedir = D;
            app.saveLabel.Text = string(app.savedir);
            date = datestr(now,'mm-dd-yy HH-MM-SS');
            splitdate = split(date," ");
            A = mat2str(cell2mat(splitdate(1)));
            B = mat2str(cell2mat(splitdate(2)));
            formatspec = 'Date: %s  Time: %s';
            app.DateandtimeLabel.Text = sprintf(formatspec,A,B); 

            app.UIAxes.Visible = 'off';
            app.ProgressTextLabel.Text = '';
            
        end

        % Value changed function: RunExperimentButton
        function RunExperiment(app, event)
           
            value = app.RunExperimentButton.Value;
            D = app.savedir;
            N = datestr(now,'mm-dd-yy HH-MM-SS');
            app.savefile = [fullfile(D,N), '.mat'];
            app.metadata.savedir = fullfile(D,N);
 
            % Set up the progress bar axis
            app.UIAxes.Visible = 'on';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YLabel.String ='';
            app.UIAxes.XLabel.String ='';
            app.UIAxes.XLim = [0,100];
            
            % Create empty patch that will be updated
            app.ph = patch(app.UIAxes,[0 0 0 0],[0 0 2 2],[0.67578 1 0.18359]); %greenyellow
         
            %run selected experiment .m file
            run testexp_1      
        end

        % Value changed function: ExperimentDropDown
        function selectExp(app, event)
            value = app.ExperimentDropDown.Value;
            app.metadata.exp = value;
        end

        % Value changed function: MouseIDDropDown
        function SelectID(app, event)
            value = app.MouseIDDropDown.Value;
            
            %numbers to strings
            Ssex = mat2str(cell2mat(app.sex(value)));
            Sgenotype = mat2str(cell2mat(app.genotype(value)));
            Sage = mat2str(cell2mat(app.age(value)));
            formatsex = 'Sex: %s';
            formatgenotype = 'Genotype: %s';
            formatage = 'Age: %s';
            
            %Print Labels 
            app.GroupLabel.Text = 'Group: '+ app.group(value);
            app.DobLabel.Text = 'Date of Birth: '+app.dob(value); 
            app.SexLabel.Text =  sprintf(formatsex, Ssex);
            app.GenotypeLabel.Text =  sprintf(formatgenotype, Sgenotype);
            app.AgeLabel.Text = sprintf(formatage, Sage);
           
            %mouse info into meta struct
            app.metadata.mouseID =  app.mouseID(value);
            app.metadata.mouseAge = app.AgeLabel.Text;
            app.metadata.group =  app.GroupLabel.Text;
            app.metadata.dob = app.DobLabel.Text ;
            app.metadata.sex = app.SexLabel.Text;
            app.metadata.genotype = app.GenotypeLabel.Text;
            
            %Experiment History 
            %[~,Exp_History] = xlsread(spreadsheet_filename, "Exp_History");
            %columnData = Exp_History(:,4);
            
        end

        % Value changed function: UserDropDown
        function selectUser(app, event)
            value = app.UserDropDown.Value;
            app.metadata.user = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserDropDownLabel = uilabel(app.UIFigure);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.UIFigure);
            app.UserDropDown.ValueChangedFcn = createCallbackFcn(app, @selectUser, true);
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @selectExp, true);
            app.DateandtimeLabel = uilabel(app.UIFigure);
            app.DateandtimeLabel.Text = 'Date and time';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Experiment name'; 'Date & Time'; 'User'; ''};
            app.UITable.RowName = {};
            app.RunExperimentButton = uibutton(app.UIFigure, 'state');
            app.RunExperimentButton.ValueChangedFcn = createCallbackFcn(app, @RunExperiment, true);
            app.RunExperimentButton.Text = 'Run Experiment';
            app.saveLabel = uilabel(app.UIFigure);
            app.saveLabel.Text = 'save ';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ProgressTextLabel = uilabel(app.UIFigure);
            app.ProgressTextLabel.Text = 'ProgressText';
            app.MousePanel = uipanel(app.UIFigure);
            app.MousePanel.Title = 'Mouse ';
            app.MouseIDDropDownLabel = uilabel(app.MousePanel);
            app.MouseIDDropDownLabel.Text = 'Mouse ID ';
            app.MouseIDDropDown = uidropdown(app.MousePanel);
            app.MouseIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectID, true);
            app.DobLabel = uilabel(app.MousePanel);
            app.DobLabel.Text = 'Dob';
            app.SexLabel = uilabel(app.MousePanel);
            app.SexLabel.Text = 'Sex';
            app.GroupLabel = uilabel(app.MousePanel);
            app.GroupLabel.Text = 'Group';
            app.GenotypeLabel = uilabel(app.MousePanel);
            app.GenotypeLabel.Text = 'Genotype';
            app.AgeLabel = uilabel(app.MousePanel);
            app.AgeLabel.Text = 'Age';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end